classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        EulerLabel_2                matlab.ui.control.Label
        BRLabel_2                   matlab.ui.control.Label
        FCLabel_2                   matlab.ui.control.Label
        EulervolgordeLabel          matlab.ui.control.Label
        EulerSequenceDropDown       matlab.ui.control.DropDown
        EulerSequenceDropDownLabel  matlab.ui.control.Label
        FrameEditField              matlab.ui.control.NumericEditField
        FrameEditFieldLabel         matlab.ui.control.Label
        FrameindexLabel             matlab.ui.control.Label
        KiessegmentLabel            matlab.ui.control.Label
        FrameSlider                 matlab.ui.control.Slider
        FrameSliderLabel            matlab.ui.control.Label
        SegmentDropDown             matlab.ui.control.DropDown
        SegmentDropDownLabel        matlab.ui.control.Label
        UIAxes                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        % Gevilterde marker‐tabel (zoals loadAndFilterTSV)
    FilteredData  

    % Lokale asstelsels (N×3×3 arrays)
    F  
    U  
    T  
    P  
    TL  
    SL  

    % Relatieve rotatiematrices (N×3×3 arrays)
    R_rel_UT  
    R_rel_FU  
    R_rel_TP  
    R_rel_STL  

    % Euler‐hoeken (N×3)
    Euler_shoulder  
    Euler_elbow  
    Euler_core  
    Euler_pelvis  
    Euler_knee  

    % Aantal frames (hoogte FilteredData)
    nFrames  

     % Event‐indices
    FC_index
    BR_index
    BR_time

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, ezd)
        % 1) Laad en filter de TSV (gebruik dezelfde filename als in main.m)
        %    (Zet hier de ’10Ax1.tsv’ in dezelfde folder als je .mlapp, of geef volledig pad.)
        filename = '10Ax1.tsv';
        fc = 10;
        fs = 300;
        app.FilteredData = loadAndFilterTSV(filename, fc, fs);

        % 2) Bereken lokale assenstelsels
        [app.F, app.U, app.T, app.P, app.TL, app.SL] = computeLocalFrames(app.FilteredData);

        % 3) Bereken relatieve rotatiematrices
        [app.R_rel_UT, app.R_rel_FU, app.R_rel_TP, app.R_rel_STL] = computeRelativeRotations(app.U, app.F, app.T, app.P, app.TL, app.SL);

        % 4) Bereken Euler‐hoeken (optioneel; gebruiken we nu niet in de plot,
        %    maar wel als je later Euler‐waarden wil laten zien)
        [app.Euler_shoulder, app.Euler_elbow, app.Euler_core, app.Euler_pelvis,  app.Euler_knee] = computeEulerAngles(app.R_rel_UT, app.R_rel_FU, app.R_rel_TP, app.R_rel_STL, app.U, app.T, app.P, app.TL, app.F);

        % 5) Bepaal aantal frames uit de hoogte van de gefilterde tabel
        app.nFrames = height(app.FilteredData);

        % 6) Configureer slider en editfield
        app.FrameSlider.Limits    = [1, app.nFrames];
        app.FrameSlider.MajorTicks = [];    % je kunt ticks weglaten of opwaarden
        app.FrameSlider.Value     = 1;      % start op frame 1
        app.FrameEditField.Limits = [1, app.nFrames];
        app.FrameEditField.Value  = 1;

        
        % --- detecteer events ---
        window = 410:470;               % vaste window, kun je parametriseren
        app.FC_index = computeFootContactLeftLeg(app.FilteredData, window, fs);
        [app.BR_index, app.BR_time] = computeBallRelease(app.FilteredData, app.FC_index, fs);
        
         % Toon initialisatie in je labels (gebruik _2-componenten)
        app.FCLabel_2.Text    = sprintf('FC: frame %d', app.FC_index);
        app.BRLabel_2.Text    = sprintf('BR: frame %d (t=%.3fs)', app.BR_index, app.BR_time);
        app.EulerLabel_2.Text = 'Euler: –';
        
        % 7) Teken de beginplot (segment = default “Upperarm”, frame = 1)
        app.updatePlot;

        end

        function updatePlot(app)
        % Schoonmaken
        cla(app.UIAxes)
        hold(app.UIAxes,'on')
        axis(app.UIAxes,'equal'), grid(app.UIAxes,'on')
        xlabel(app.UIAxes,'X (mm)'), ylabel(app.UIAxes,'Y (mm)'), zlabel(app.UIAxes,'Z (mm)')
    
        % Plot elke 10 frames
        step = 10;
        seg = lower(app.SegmentDropDown.Value);
        for f = 1:step:app.nFrames
            switch seg
                case 'upperarm'
                    M = [app.FilteredData.ARX,app.FilteredData.ARY,app.FilteredData.ARZ]; origin=M(f,:);
                    Rm = squeeze(app.U(f,:,:));
                case 'forearm'
                    M = [app.FilteredData.PMRX,app.FilteredData.PMRY,app.FilteredData.PMRZ]; origin=M(f,:);
                    Rm = squeeze(app.F(f,:,:));
                case 'thorax'
                    M = [app.FilteredData.MSX, app.FilteredData.MSY, app.FilteredData.MSZ]; origin=M(f,:);
                    Rm = squeeze(app.T(f,:,:));
                case 'pelvis'
                    M = [app.FilteredData.HLX,app.FilteredData.HLY,app.FilteredData.HLZ]; origin=M(f,:);
                    Rm = squeeze(app.P(f,:,:));
                case 'left thigh'
                    M = [app.FilteredData.HLX,app.FilteredData.HLY,app.FilteredData.HLZ]; origin=M(f,:);
                    Rm = squeeze(app.TL(f,:,:));
                case 'left shank'
                    M1=[app.FilteredData.MLLX,app.FilteredData.MLLY,app.FilteredData.MLLZ];
                    M2=[app.FilteredData.MMLX,app.FilteredData.MMLY,app.FilteredData.MMLZ];
                    origin=0.5*(M1(f,:)+M2(f,:)); Rm=squeeze(app.SL(f,:,:));
                otherwise
                    origin=[0,0,0]; Rm=eye(3);
            end
            % plot axes
            Xd=Rm(:,1); Yd=Rm(:,2); Zd=Rm(:,3); sc=50;
            quiver3(app.UIAxes,origin(1),origin(2),origin(3),sc*Xd(1),sc*Xd(2),sc*Xd(3),'r','MaxHeadSize',0.3);
            quiver3(app.UIAxes,origin(1),origin(2),origin(3),sc*Yd(1),sc*Yd(2),sc*Yd(3),'g','MaxHeadSize',0.3);
            quiver3(app.UIAxes,origin(1),origin(2),origin(3),sc*Zd(1),sc*Zd(2),sc*Zd(3),'b','MaxHeadSize',0.3);
        end
        legend(app.UIAxes,{'X','Y','Z'},'Location','northeast')
    
        % Event-markers
        if strcmp(seg,'left shank')
            M1=[app.FilteredData.MLLX,app.FilteredData.MLLY,app.FilteredData.MLLZ];
            M2=[app.FilteredData.MMLX,app.FilteredData.MMLY,app.FilteredData.MMLZ];
            pt=0.5*(M1(app.FC_index,:)+M2(app.FC_index,:));
            scatter3(app.UIAxes,pt(1),pt(2),pt(3),60,'m','filled')
        end
        if strcmp(seg,'forearm')
            PLR=[app.FilteredData.PLRX,app.FilteredData.PLRY,app.FilteredData.PLRZ];
            pt=PLR(app.BR_index,:);
            scatter3(app.UIAxes,pt(1),pt(2),pt(3),60,'c','filled')
        end
    
        % Euler-hoeken huidig frame
        curF = round(app.FrameSlider.Value);
        seq  = app.EulerSequenceDropDown.Value;
        switch seq
            case 'Y-X-Y (ISB shoulder)'
                Re = squeeze(app.R_rel_UT(curF,:,:)); a=rotm2eul(Re,'YXY');
                txt=sprintf('γ=%.1f°,β=%.1f°,α=%.1f°',rad2deg(a));
            case 'Z-X-Y (ISB elbow)'
                Re = squeeze(app.R_rel_FU(curF,:,:)); a=rotm2eul(Re,'ZXY');
                txt=sprintf('γ=%.1f°,β=%.1f°,α=%.1f°',rad2deg(a));
            case 'X-Y-Z'
                Re = squeeze(app.R_rel_TP(curF,:,:)); a=rotm2eul(Re,'XYZ');
                txt=sprintf('ϕ=%.1f°,θ=%.1f°,ψ=%.1f°',rad2deg(a));
            case 'Y-Z-X'
                Re = squeeze(app.R_rel_TP(curF,:,:)); a=rotm2eul(Re,'YZX');
                txt=sprintf('ϕ=%.1f°,θ=%.1f°,ψ=%.1f°',rad2deg(a));
            otherwise
                txt='';
        end
        app.EulerLabel_2.Text = ['Euler: ' txt];
    
        hold(app.UIAxes,'off')
        

        % 4) Maak UIAxes leeg en plot de drie assen als quiver3
    
        end

        % Value changed function: FrameSlider
        function FrameSliderValueChanged(app, event)
        % 1) Lees het nieuwe slider‐waarde (kan non‐integer zijn → afronden)
        newFrame = round(app.FrameSlider.Value);

        % 2) Zet de editfield gelijk aan die ronde waarde
        app.FrameEditField.Value = newFrame;

        % 3) Update de plot
        app.updatePlot;
        end

        % Value changed function: FrameEditField
        function FrameEditFieldValueChanged(app, event)
        % 1) Lees het nieuwe getal in de editfield
        newFrame = round(app.FrameEditField.Value);
    
        % 2) Beveilig dat newFrame niet onder 1 of boven nFrames valt
        newFrame = max(1, min(app.nFrames, newFrame));

        % 3) Zet de slider‐waarde op dezelfde frame (slider accepteert reëel getal,
        %    dus als we een integer binnenzetten, zit deze daar exact)
        app.FrameSlider.Value = newFrame;

        % 4) Update de plot
        app.updatePlot;
        end

        % Value changed function: SegmentDropDown
        function SegmentDropDownValueChanged(app, event)
        app.updatePlot;
        end

        % Value changed function: EulerSequenceDropDown
        function EulerSequenceDropDownValueChanged(app, event)
            app.updatePlot;
        
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Axes3D')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.SegmentDropDownLabel = uilabel(app.UIFigure);
            app.SegmentDropDownLabel.Text = 'Segment Drop Down';
            app.SegmentDropDown = uidropdown(app.UIFigure);
            app.SegmentDropDown.Items = {'Upperarm', 'Forearm', 'Thorax', 'Pelvis', 'Left thigh', 'Left shank'};
            app.SegmentDropDown.ValueChangedFcn = createCallbackFcn(app, @SegmentDropDownValueChanged, true);
            app.SegmentDropDown.Value = 'Upperarm';
            app.FrameSliderLabel = uilabel(app.UIFigure);
            app.FrameSliderLabel.Text = 'Frame-Slider';
            app.FrameSlider = uislider(app.UIFigure);
            app.FrameSlider.MajorTicks = [];
            app.FrameSlider.ValueChangedFcn = createCallbackFcn(app, @FrameSliderValueChanged, true);
            app.KiessegmentLabel = uilabel(app.UIFigure);
            app.KiessegmentLabel.Text = 'Kies segment:';
            app.FrameindexLabel = uilabel(app.UIFigure);
            app.FrameindexLabel.Text = 'Frame-index';
            app.FrameEditFieldLabel = uilabel(app.UIFigure);
            app.FrameEditFieldLabel.Text = ' Frame Edit Field';
            app.FrameEditField = uieditfield(app.UIFigure, 'numeric');
            app.FrameEditField.Limits = [1 100];
            app.FrameEditField.ValueChangedFcn = createCallbackFcn(app, @FrameEditFieldValueChanged, true);
            app.FrameEditField.Value = 1;
            app.EulerSequenceDropDownLabel = uilabel(app.UIFigure);
            app.EulerSequenceDropDownLabel.Text = 'EulerSequenceDropDown';
            app.EulerSequenceDropDown = uidropdown(app.UIFigure);
            app.EulerSequenceDropDown.Items = {'Y-X-Y (ISB shoulder)', 'Z-X-Y (ISB elbow)', 'X-Y-Z ', 'Y-Z-X'};
            app.EulerSequenceDropDown.ValueChangedFcn = createCallbackFcn(app, @EulerSequenceDropDownValueChanged, true);
            app.EulerSequenceDropDown.Value = 'Y-X-Y (ISB shoulder)';
            app.EulervolgordeLabel = uilabel(app.UIFigure);
            app.EulervolgordeLabel.Text = 'Euler-volgorde';
            app.FCLabel_2 = uilabel(app.UIFigure);
            app.FCLabel_2.Tag = 'FCLabel_2';
            app.FCLabel_2.Text = '"FC:-"';
            app.BRLabel_2 = uilabel(app.UIFigure);
            app.BRLabel_2.Tag = 'BRLabel_2';
            app.BRLabel_2.Text = '"BR:-"';
            app.EulerLabel_2 = uilabel(app.UIFigure);
            app.EulerLabel_2.Tag = 'EulerLabel_2';
            app.EulerLabel_2.Text = '"Euler:-"';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end